classdef CPSProject_App10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        closeappButton   matlab.ui.control.Button
        GoBackButton     matlab.ui.control.Button
        RefreshButton_3  matlab.ui.control.Button
        Image4           matlab.ui.control.Image
        OptionsLabel     matlab.ui.control.Label
        RefreshButton_2  matlab.ui.control.Button
        RefreshButton    matlab.ui.control.Button
        Image2           matlab.ui.control.Image
        Image            matlab.ui.control.Image
        Image3           matlab.ui.control.Image
    end


      properties (Access = public)
        Property % Description
       AccessoryDirectory
       AccessoryDirectory2
       AccessoryDirectory3
       JewelryCnt = 2;
       HairCnt = 2;
       ShoesCnt = 2;
    end
    methods (Access = public, Static)
        function setAccessoryDirectory(app12, Jewelry,Hair,Shoes)
            app12.AccessoryDirectory = Jewelry;
            app12.AccessoryDirectory2 = Hair;
            app12.AccessoryDirectory3 = Shoes; 
            app12.Image.ImageSource = "Accessories\" + app12.AccessoryDirectory(1).name
            app12.Image2.ImageSource = "Accessories\" + app12.AccessoryDirectory2(1).name
            app12.Image4.ImageSource = "Accessories\" + app12.AccessoryDirectory3(1).name
        end 
    end 


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
             if app.JewelryCnt < 10
          app.Image.ImageSource = "Accessories\"+ app.AccessoryDirectory(app.JewelryCnt).name
          app.JewelryCnt  = app.JewelryCnt  +1 
         

          end 
        end

        % Button pushed function: RefreshButton_2
        function RefreshButton_2Pushed(app, event)
             if app.HairCnt < 10
          app.Image2.ImageSource = "Accessories\"+ app.AccessoryDirectory2(app.HairCnt).name
          app.HairCnt  = app.HairCnt  +1 
             end 
        end

        % Button pushed function: RefreshButton_3
        function RefreshButton_3Pushed(app, event)
            if app.ShoesCnt < 10
          app.Image4.ImageSource = "Accessories\"+ app.AccessoryDirectory3(app.ShoesCnt).name
          app.ShoesCnt  = app.ShoesCnt  +1 
             end 
        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            CPSProject_App9
            app.delete
        end

        % Button pushed function: closeappButton
        function closeappButtonPushed(app, event)
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'BridesMaidOptions2.png');
            app.Image = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.RefreshButton = uibutton(app.UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.RefreshButton_2 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_2.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_2Pushed, true);
            app.RefreshButton_2.Text = 'Refresh';
            app.OptionsLabel = uilabel(app.UIFigure);
            app.OptionsLabel.Text = 'Options';
            app.Image4 = uiimage(app.UIFigure);
            app.RefreshButton_3 = uibutton(app.UIFigure, 'push');
            app.RefreshButton_3.ButtonPushedFcn = createCallbackFcn(app, @RefreshButton_3Pushed, true);
            app.RefreshButton_3.Text = 'Refresh';
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.closeappButton = uibutton(app.UIFigure, 'push');
            app.closeappButton.ButtonPushedFcn = createCallbackFcn(app, @closeappButtonPushed, true);
            app.closeappButton.Text = 'close app';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end