classdef CPSProject_App4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GotomainButton                 matlab.ui.control.Button
        GoBackButton                   matlab.ui.control.Button
        Slider                         matlab.ui.control.Slider
        SliderLabel                    matlab.ui.control.Label
        SeeDressinDifferentColorLabel  matlab.ui.control.Label
        Image2                         matlab.ui.control.Image
        Image                          matlab.ui.control.Image
    end

    
    properties (Access = public)
        ColorDressDirectory
    end
    
    methods (Access = public,Static)
        
        function setColorDressDirectory(app2,image)
             app2.ColorDressDirectory= image;
            app2.Image2.ImageSource = app2.ColorDressDirectory

           
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Image clicked function: Image2
        function Image2Clicked(app, event)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            
            I1 = app.Image2.ImageSource
            I = imread(I1);
           
               
            isRed = I(:,:,1)>150 & I(:,:,2)>150 & I(:,:,3)>150;
isRedArray = repmat(isRed,1,1,3); 
I_temp = I; 
I_temp(isRedArray) = 0;
I_new = I; 
           
I_new(isRedArray) = I_new(isRedArray)- value; 
app.Image2.ImageSource = I_new;

        end

        % Button pushed function: GoBackButton
        function GoBackButtonPushed(app, event)
            CPSProject_App2
            app.delete
        end

        % Button pushed function: GotomainButton
        function GotomainButtonPushed(app, event)
            CPSProject_App1_2
            app.delete
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'whiteBlankFlowerTop2.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.SeeDressinDifferentColorLabel = uilabel(app.UIFigure);
            app.SeeDressinDifferentColorLabel.Text = 'See Dress in Different Color';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.GoBackButton = uibutton(app.UIFigure, 'push');
            app.GoBackButton.ButtonPushedFcn = createCallbackFcn(app, @GoBackButtonPushed, true);
            app.GoBackButton.Text = 'Go Back';
            app.GotomainButton = uibutton(app.UIFigure, 'push');
            app.GotomainButton.ButtonPushedFcn = createCallbackFcn(app, @GotomainButtonPushed, true);
            app.GotomainButton.Text = 'Go to main';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end