classdef CPSProject_App7 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Slider                     matlab.ui.control.Slider
        SliderLabel                matlab.ui.control.Label
        DressInDiffrentColorLabel  matlab.ui.control.Label
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        ColorDressDirectory2
    end


    methods (Access = public, Static)
        
        function setColorDressDirectory2(app4, image2)
            app4.ColorDressDirectory2 = image2;
            app4.Image2.ImageSource = app4.ColorDressDirectory2
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider
        function SliderValueChanged(app, event)
           
            value = app.Slider.Value;
            
            I2 = app.Image2.ImageSource;
            I4 = imread(I2);
           
               
        isRed1 = I4(:,:,1)< 70 & I4(:,:,2)< 70 & I4(:,:,3) < 70;
        isRedArray1 = repmat(isRed1,1,1,3); 
        I_temp1 = I4; 
        I_temp1(isRedArray1) = 0;
        I_new1 = I4; 
           
        I_new1(isRedArray1) = I_new1(isRedArray1) + value; 
        app.Image2.ImageSource = I_new1;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'Flower Floral Wedding Invitation.jpg';
            app.Image2 = uiimage(app.UIFigure);
            app.DressInDiffrentColorLabel = uilabel(app.UIFigure);
            app.DressInDiffrentColorLabel.Text = 'Dress In Diffrent Color';
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CPSProject_App7

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end